classdef dataRecording < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ForcePanel                     matlab.ui.container.Panel
        edit_MVCvalue                  matlab.ui.control.NumericEditField
        CurveDropDown                  matlab.ui.control.DropDown
        CurveDropDownLabel             matlab.ui.control.Label
        LevelEditField                 matlab.ui.control.NumericEditField
        LevelEditFieldLabel            matlab.ui.control.Label
        MVCButton                      matlab.ui.control.Button
        ForceCheckBox                  matlab.ui.control.CheckBox
        DeviceDropDown_2               matlab.ui.control.DropDown
        DeviceDropDown_2Label          matlab.ui.control.Label
        ConfigurePanel                 matlab.ui.container.Panel
        ProbeEditField                 matlab.ui.control.EditField
        ProbeEditFieldLabel            matlab.ui.control.Label
        DeviceDropDown                 matlab.ui.control.DropDown
        DeviceDropDownLabel            matlab.ui.control.Label
        ConfigureButton                matlab.ui.control.Button
        PreviewPanel                   matlab.ui.container.Panel
        IntervalEditField              matlab.ui.control.NumericEditField
        IntervalEditFieldLabel         matlab.ui.control.Label
        SelectedchannelEditField       matlab.ui.control.NumericEditField
        SelectedchannelEditFieldLabel  matlab.ui.control.Label
        FFTButton                      matlab.ui.control.Button
        ScalesEditField                matlab.ui.control.NumericEditField
        ScalesEditFieldLabel           matlab.ui.control.Label
        ChannelEditField               matlab.ui.control.EditField
        ChannelEditFieldLabel          matlab.ui.control.Label
        PreviewButton                  matlab.ui.control.Button
        CombCheckBox                   matlab.ui.control.CheckBox
        BandpassCheckBox               matlab.ui.control.CheckBox
        RecordPanel                    matlab.ui.container.Panel
        PrefixEditField                matlab.ui.control.EditField
        PrefixEditFieldLabel           matlab.ui.control.Label
        SessionSpinner                 matlab.ui.control.Spinner
        SessionSpinnerLabel            matlab.ui.control.Label
        TaskSpinner                    matlab.ui.control.Spinner
        TaskSpinnerLabel               matlab.ui.control.Label
        TrialSpinner                   matlab.ui.control.Spinner
        TrialSpinnerLabel              matlab.ui.control.Label
        TimesEditField                 matlab.ui.control.NumericEditField
        TimesEditFieldLabel            matlab.ui.control.Label
        AutostopCheckBox               matlab.ui.control.CheckBox
        DateCheckBox                   matlab.ui.control.CheckBox
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        UIAxes_force                   matlab.ui.control.UIAxes
        UIAxes_EMG                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        deviceName 
        probe
        startCommand
        stopCommand
        tcpSocket
        samplingRate
        adn
        gain

        readChannel
        recordChannel
        viewChannel
        readChannelNum
        recordChannelNum
        viewChannelNum
        
        plotEMG
        plotTime
        
        bandpassFilterPara
        combFilterPara

        data_preview
        data_sEMG
        recordFlag
        recordWin

        forceDevice
        forcePara
        mvcData
        mvcPara
        data_force
        plotForce
        plotRefForce
        
        data_labels
        lib_labels
        
    end
    
    methods (Access = private)
        
        function startRecord(app)
            switch app.deviceName
                case 'MuoviPro'
                    write(app.tcpSocket,app.startCommand,'uint8');
                    while app.tcpSocket.NumBytesAvailable
                        tmp = read(app.tcpSocket,app.tcpSocket.NumBytesAvailable);
                    end
                    app.tcpSocket.NumBytesAvailable
                case 'Quattrocento'
                    try
                        fopen(app.tcpSocket);
                    catch
                    end
                    fwrite(app.tcpSocket,app.startCommand);
                    pause(0.01)
                    name = fread(app.tcpSocket,8);
                    disp((char(name')));
                    while app.tcpSocket.BytesAvailable
                        tmp = fread(app.tcpSocket,app.tcpSocket.BytesAvailable);
                    end
                    app.tcpSocket.BytesAvailable
            end
        end
        function stopRecord(app)
            switch app.deviceName
                case 'MuoviPro'
                    write(app.tcpSocket,app.stopCommand,'uint8');
                case 'Quattrocento'%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    fwrite(app.tcpSocket,app.stopCommand);
                    fclose(app.tcpSocket);
            end 
        end
        function data = readData(app)
            switch app.deviceName
                case 'MuoviPro'
                    tmp = read(app.tcpSocket,app.readChannelNum*app.recordWin,'int16');
                    tmp = reshape(double(tmp),[app.readChannelNum,app.recordWin]);
                    tmpInd = find(tmp>32768);
                    tmp(tmpInd) = tmp(tmpInd)-65536;
                    data = tmp(app.recordChannel,:)*0.000286;
                case 'Quattrocento'
                    data = fread(app.tcpSocket,[app.readChannelNum,app.recordWin],'int16');
                    data = data*5/2^app.adn/app.gain*1000;
                    data = data(app.recordChannel,:);%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            end
            
        end
        function data = readAuxData(app,t)
            switch app.forceDevice
                case  'Biometrics'
                    getData(app.forcePara.ch, app.forcePara.fsamp, 1000, app.forcePara.values);
                    tmpData = double(app.forcePara.values.pvData);
                    % tmpData = -tmpData;
                    if app.MVCButton.BackgroundColor==[1,1,1]
                        data = (tmpData-app.mvcPara.mi)/(app.mvcPara.ma-app.mvcPara.mi);  
                    else                        
                        data = tmpData;
                    end
%                     tmpData(isnan(tmpData)) = [];
%                     data = (mean(tmpData)-app.mvcPara.mi)/(app.mvcPara.ma-app.mvcPara.mi);        
                case 'Handwriting'
                    tri = app.TrialSpinner.Value;
                    data = app.lib_labels((tri-1)*10+1:tri*10);
                    ind = ceil((max(t)-5)/2);
                    if ind>0 && ind<=length(data)
                        data = data(ind);
                    else
                        data = NaN;
                        %     set(app.plotForce,'string',data(ind));
                        % else
                        %     set(app.plotForce,'string',[]);
                    end
                    data%%%%%%been deleted
                case 'Gestures'
                    data = max(t);
            end

        end
        function data = filterData(app,data)
            for ch = 1:size(data,1)
                if app.BandpassCheckBox.Value
                    data(ch,:) = filter(app.bandpassFilterPara(1,:),app.bandpassFilterPara(2,:),data(ch,:));
                end
                if app.CombCheckBox.Value
                    data(ch,:) = filter(app.combFilterPara(1,:),app.combFilterPara(2,:),data(ch,:));
                end
            end
        end
        function plotData(app,t,dataToPlot)
            for ch = 1:size(dataToPlot,1)
                set(app.plotEMG{ch},'xdata',t,'ydata',dataToPlot(ch,:)+ch);
            end
            % app.UIAxes_EMG.XLim = [max([t(1),0]) max([t(end),app.plotTime])];
            tmp = ceil(t(end)/app.plotTime);
            app.UIAxes_EMG.XLim = [(tmp-1)*app.plotTime,tmp*app.plotTime];
            drawnow;
        end
        function plotData_all(app,data)
            cla(app.UIAxes_EMG);
            chInt = 8;
            app.plotEMG = multiChanPlotGeneration(size(data,1)/chInt,app.UIAxes_EMG);
            t = [1:length(data)]/app.samplingRate;
            for ch = chInt:chInt:size(data,1)
                set(app.plotEMG{ch/chInt},'xdata',t,'ydata',data(ch,:)+ch/chInt);
            end
            app.UIAxes_EMG.XLim = [0,length(data)/app.samplingRate];
            app.UIAxes_EMG.YLim = [0,size(data,1)/chInt+1];
        end
        function plotAuxData(app,type,data,t)
            switch type
                case 'offline'
                    if strcmp(app.forceDevice,'Handwriting')
                        set(app.plotForce,'string',data);
                    elseif strcmp(app.forceDevice,'Gestures')
                    else
                        if max(abs(data))<2
                            data = data*100;
                            tmpF = app.forcePara.fsamp;%app.samplingRate/app.recordWin;
                        else
                            tmpF = app.forcePara.fsamp;
                        end
                        t = [1:length(data)]/tmpF;
                        set(app.plotForce,'xdata',t,'ydata',data,'linestyle','-','linewidth',2,'color',[0,0,0]);
                        app.UIAxes_force.XLim = [0,length(data)/tmpF];
                    end
                case 'online'
                    if strcmp(app.forceDevice,'Handwriting')
                        if isnan(data)
                            set(app.plotForce,'string',[]);
                        else
                            set(app.plotForce,'string',data);
                        end
                    elseif strcmp(app.forceDevice,'Gestures')
                        if t<=90
                            set(app.plotForce.cursor,'xdata',[t,t]*20,'ydata',[500,1000]);
                            set(app.plotForce.shadow,'xdata',[0,0,t*20,t*20],'ydata',[0,500,500,0]+500);
                            set(app.plotForce.shadow2,'xdata',[0,0,0,0],'ydata',[0,0,0,0]);
                            set(app.plotForce.cursor2,'xdata',[-50,0,-25,0,-25]+t*20,'ydata',[250,250,275,250,225]+500);
                        else
                            set(app.plotForce.cursor,'xdata',[t-90,t-90]*20,'ydata',[0,500]);
                            set(app.plotForce.shadow,'xdata',[0,0,1800,1800],'ydata',[0,500,500,0]+500);
                            set(app.plotForce.shadow2,'xdata',[0,0,(t-90)*20,(t-90)*20],'ydata',[0,500,500,0]);
                            set(app.plotForce.cursor2,'xdata',[-50,0,-25,0,-25]+(t-90)*20,'ydata',[250,250,275,250,225]);
                        end
                    else
                        set(app.plotForce,'xdata',t,'ydata',data*100,Marker=".",MarkerSize=40);
                        app.plotTime
                        tmp = ceil(t(end)/app.plotTime);
                        app.UIAxes_force.XLim = [(tmp-1)*app.plotTime,tmp*app.plotTime];
                    end
                    
                    % app.UIAxes_force.YLim = [0,100];
                case 'MVC'
                    set(app.plotForce,'xdata',t,'ydata',data,Marker=".",MarkerSize=40);
                    tmp = ceil(t(end)/app.plotTime);
                    app.UIAxes_force.XLim = [(tmp-1)*app.plotTime,tmp*app.plotTime];
                case 'preview'
                    if strcmp(app.forceDevice,'Handwriting')
                        if isnan(data)
                            set(app.plotForce,'string',[]);
                        else
                            set(app.plotForce,'string',data);
                        end
                    elseif strcmp(app.forceDevice,'Gestures')
                        if t<=90
                            set(app.plotForce.cursor,'xdata',[t,t]*20,'ydata',[500,1000]);
                            set(app.plotForce.shadow,'xdata',[0,0,t*20,t*20],'ydata',[0,500,500,0]+500);
                            set(app.plotForce.cursor2,'xdata',[-50,0,-25,0,-25]+t*20,'ydata',[250,250,275,250,225]+500);
                        else
                            set(app.plotForce.cursor,'xdata',[t-90,t-90]*20,'ydata',[0,500]);
                            set(app.plotForce.shadow,'xdata',[0,0,1800,1800],'ydata',[0,500,500,0]+500);
                            set(app.plotForce.shadow2,'xdata',[0,0,(t-90)*20,(t-90)*20],'ydata',[0,500,500,0]);
                            set(app.plotForce.cursor2,'xdata',[-50,0,-25,0,-25]+(t-90)*20,'ydata',[250,250,275,250,225]);
                        end
                    else
                        set(app.plotForce,'xdata',t,'ydata',data*100,Marker=".",MarkerSize=40);
                        tmp = ceil(t(end)/app.plotTime);
                        app.UIAxes_force.XLim = [(tmp-1)*app.plotTime,tmp*app.plotTime];
                        app.UIAxes_force.YLim = [0,100];
                    end
            end

        end
        function plotInterval(app)
            interval = app.IntervalEditField.Value;
            if interval
                for i = 1:20
                    line(app.UIAxes_EMG,[interval,interval]*i,[0,500],'LineWidth',2,'Color','k');
                end
                app.UIAxes_EMG.XLim = [0,app.plotTime];
            end
        end
        function saveData(app)
            fileName = [app.PrefixEditField.Value '_Motion' num2str(app.SessionSpinner.Value) '_Level' num2str(app.TaskSpinner.Value) '_Trial' num2str(app.TrialSpinner.Value) '.mat'];
            if isempty(app.PrefixEditField.Value)
                fileName = fileName(2:end);
            end
            if app.DateCheckBox.Value
                fileName = [fileName(1:end-4) '_' char(datetime("today")) '.mat'];
            end
            data_sEMG = app.data_sEMG;
            save(fileName,'data_sEMG');
            if app.ForceCheckBox.Value
                if strcmp(app.forceDevice,'Handwriting')
                    data_label = app.data_force;
                    save(fileName,'data_label','-append');
                else
                    data_force = app.data_force;
                    data_mvc = app.mvcData;
                    save(fileName,'data_force','data_mvc','-append');
                end
            end
            app.TrialSpinner.Value = app.TrialSpinner.Value+1;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConfigureButton
        function ConfigureButtonPushed(app, event)
            app.ConfigureButton.BackgroundColor = [1,1,1];
            app.deviceName = app.DeviceDropDown.Value;
            app.probe = str2num(app.ProbeEditField.Value);
            [app.startCommand,app.stopCommand] = OTconfigure(app.deviceName,app.probe);

            switch app.deviceName
                case 'MuoviPro'
                    app.readChannelNum = 6+38*length(app.probe);
                    app.readChannel = 1:app.readChannelNum;

                    app.recordChannel = [];
                    for i = 1:length(app.probe)
                        app.recordChannel = [app.recordChannel,[1:64]+(i-1)*64];
                    end
                    app.recordChannelNum = length(app.recordChannel);

                    app.tcpSocket = tcpclient('192.168.76.1',54320);
                    app.tcpSocket.ByteOrder = 'big-endian';
                    app.samplingRate = 2000;
                case 'Quattrocento'
                    app.tcpSocket = tcpclient('localhost',31000);
                    set(app.tcpSocket, 'ByteOrder', 'little-Endian');
                   
                    app.readChannelNum = 384+16+8;
                    app.readChannel = 1:app.readChannelNum;

                    for i = 1:length(app.probe)
                        app.recordChannel = [app.recordChannel,[1:64]+(i-1)*64];
                    end
                    app.recordChannelNum = length(app.recordChannel);
                    app.adn = 16;
                    app.gain = 150;
                    app.samplingRate = 2048;
                    app.tcpSocket.InputBufferSize = 5*app.samplingRate*app.readChannelNum;
            end

            app.ChannelEditField.Value = ['8:64:' num2str(app.recordChannelNum)];

            [app.bandpassFilterPara(1,:),app.bandpassFilterPara(2,:)] = butter(4,[20/app.samplingRate*2 500/app.samplingRate*2]);
            fo = 50; % power frequency
            q = 10;
            bw = (fo/(app.samplingRate/2))/q;
            [app.combFilterPara(1,:),app.combFilterPara(2,:)] = iircomb(round(app.samplingRate/fo),bw,'notch');

            app.plotTime = app.ScalesEditField.Value;

            fprintf('Configuration completed!\n');
            app.ConfigureButton.BackgroundColor = [0,1,0];
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            if app.PreviewButton.BackgroundColor==[1,1,1]               
                app.PreviewButton.BackgroundColor = [0,1,0];
                app.viewChannel = str2num(app.ChannelEditField.Value);
                app.viewChannelNum = length(app.viewChannel);

                cla(app.UIAxes_EMG);
                app.plotEMG = multiChanPlotGeneration(app.viewChannelNum,app.UIAxes_EMG);
                
                plotInterval(app);                
                app.UIAxes_EMG.YLim = [0,app.viewChannelNum+1];

                app.data_preview = zeros(app.recordChannelNum,0);
                app.recordFlag = 1;
                app.recordWin = round(0.2*app.samplingRate);
                
                t = [1:app.plotTime*app.samplingRate]/app.samplingRate-app.plotTime;
                dataToPlot = zeros(app.viewChannelNum, app.plotTime*app.samplingRate);
                dataRaw = zeros(app.viewChannelNum, 5*app.samplingRate);
  
                nCycle = 1;

                startRecord(app);
                pause(0.1);
                while app.recordFlag
                    t = t+app.recordWin/app.samplingRate;
                    tmpData = readData(app);
                    app.data_preview(:,(nCycle-1)*app.recordWin+1:nCycle*app.recordWin) = tmpData;           
                    dataRaw = [dataRaw(:,app.recordWin+1:end),tmpData(app.viewChannel,:)];

                    dataFilter = filterData(app,dataRaw); 
                    dataToPlot = [dataToPlot(:,app.recordWin+1:end),dataFilter(:,end-app.recordWin+1:end)];
                    
                    plotData(app,t,dataToPlot);       

                    if app.ForceCheckBox.Value
                        tmpData2 = readAuxData(app,t);
                        if length(tmpData2)>1
                            tmpData2(isnan(tmpData2)) = [];
                            tmpData2 = mean(tmpData2);
                        end
                        plotAuxData(app,'preview',tmpData2,t(end));
                    end

                    nCycle = nCycle+1;
                end
                % stopRecord(app);
                app.PreviewButton.BackgroundColor = [1,1,1];       
                plotData_all(app,app.data_preview);
               
            else
                app.recordFlag = 0;   
                app.PreviewButton.BackgroundColor = [1,1,1];  
                pause(1);
                stopRecord(app);
            end


        end

        % Value changed function: ProbeEditField
        function ProbeEditFieldValueChanged(app, event)
            app.probe = str2num(app.ProbeEditField.Value);         
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.BackgroundColor = [0,1,0];
            app.viewChannel = str2num(app.ChannelEditField.Value);
            app.viewChannelNum = length(app.viewChannel);

            % CurveDropDownValueChanged(app, event);

            cla(app.UIAxes_EMG);
            app.plotEMG = multiChanPlotGeneration(app.viewChannelNum,app.UIAxes_EMG);            
            plotInterval(app);
            app.UIAxes_EMG.YLim = [0,app.viewChannelNum+1];

            app.recordFlag = 1;
            app.recordWin = round(0.2*app.samplingRate);

            app.data_force = zeros(0);
            if app.AutostopCheckBox.Value
                nCycle_all = ceil(app.TimesEditField.Value*app.samplingRate/app.recordWin);
                app.data_sEMG = zeros(app.recordChannelNum,nCycle_all*app.recordWin);
            else
                nCycle_all = inf;
                app.data_sEMG = zeros(app.recordChannelNum,0);
                % app.data_force = zeros(0);
            end

            t = [1:app.plotTime*app.samplingRate]/app.samplingRate-app.plotTime;
            dataToPlot = zeros(app.viewChannelNum, app.plotTime*app.samplingRate);
            dataRaw = zeros(app.viewChannelNum, 5*app.samplingRate);

            if app.ForceCheckBox.Value
                if strcmp(app.forceDevice,'Biometrics')
                    getData(app.forcePara.ch, getSampleRate(app.forcePara.ch), 50000, app.forcePara.values);
                end
            end
            
            nCycle = 1;

            startRecord(app);
            pause(0.01);
            while app.recordFlag && nCycle<=nCycle_all
                t = t+app.recordWin/app.samplingRate;
                tmpData = readData(app);

                if app.ForceCheckBox.Value
                    tmpData2 = readAuxData(app,t);
                    app.data_force = [app.data_force,tmpData2];
                    if length(tmpData2)>1
                        tmpData2(isnan(tmpData2)) = [];
                        tmpData2 = mean(tmpData2);
                    end
                    plotAuxData(app,'online',tmpData2,t(end));
                end    

                app.data_sEMG(:,(nCycle-1)*app.recordWin+1:nCycle*app.recordWin) = tmpData;
                dataRaw = [dataRaw(:,app.recordWin+1:end),tmpData(app.viewChannel,:)];

                dataFilter = filterData(app,dataRaw);
                dataToPlot = [dataToPlot(:,app.recordWin+1:end),dataFilter(:,end-app.recordWin+1:end)];

                plotData(app,t,dataToPlot);
                nCycle = nCycle+1;
            end
            stopRecord(app);
            saveData(app);
            app.StartButton.BackgroundColor = [1,1,1];

            app.plotEMG = multiChanPlotGeneration(app.recordChannelNum,app.UIAxes_EMG);
            plotData_all(app,app.data_sEMG);
            plotAuxData(app,'offline',app.data_force);
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.recordFlag = 0;
        end

        % Value changed function: TaskSpinner
        function TaskSpinnerValueChanged(app, event)
            % value = app.TaskSpinner.Value;
            app.TrialSpinner.Value = 1;
        end

        % Value changed function: SessionSpinner
        function SessionSpinnerValueChanged(app, event)
            app.TaskSpinner.Value = 1;
            app.TrialSpinner.Value = 1;
        end

        % Value changed function: AutostopCheckBox
        function AutostopCheckBoxValueChanged(app, event)
            if app.AutostopCheckBox.Value
                app.TimesEditFieldLabel.Enable = 'on';
                app.TimesEditField.Enable = 'on';
            else
                app.TimesEditFieldLabel.Enable = 'off';
                app.TimesEditField.Enable = 'off';
            end
        end

        % Button pushed function: FFTButton
        function FFTButtonPushed(app, event)
            tmpData = app.data_preview(app.SelectedchannelEditField.Value,:);
            fft_cc(tmpData,app.samplingRate);
        end

        % Value changed function: PrefixEditField
        function PrefixEditFieldValueChanged(app, event)
            app.SessionSpinner.Value = 1;
            app.TaskSpinner.Value = 1;
            app.TrialSpinner.Value = 1;
        end

        % Value changed function: ForceCheckBox
        function ForceCheckBoxValueChanged(app, event)
            app.forceDevice = app.DeviceDropDown_2.Value;            
            switch app.forceDevice
                case 'Biometrics'
                    if app.ForceCheckBox.Value
                        if ~libisloaded('OnLineInterface64')  % only load if not already loaded
                            [notfound,warnings]=loadlibrary('OnLineInterface64.dll', 'OnLineInterface.h');
                        end
                        feature('COM_SafeArraySingleDim', 1);   % only use single dimension SafeArrays
                        feature('COM_PassSafeArrayByRef', 1);
                        app.forcePara.ch = int32(18);
                        app.forcePara.values = libstruct('tagSAFEARRAY');
                        app.forcePara.fsamp = double(getSampleRate(app.forcePara.ch));
                        app.plotRefForce = plot(app.UIAxes_force,0,0,'LineWidth',2,'Color','r');
                        app.plotForce = plot(app.UIAxes_force,0,0,'.',MarkerSize=40);
                    else
                        try
                            unloadlibrary OnLineInterface64;
                        catch
                        end
                        cla(app.UIAxes_force);
                    end
                case 'Handwriting'
                    if app.ForceCheckBox.Value
                        tmp = [48:57,97:122];
                        tmp = char(tmp);
                        tmp = repmat(tmp,1,10);
                        tmpInd = randperm(length(tmp));
                        app.lib_labels = tmp(tmpInd);
                        app.plotForce = text(app.UIAxes_force,5,5,app.lib_labels(1),'fontsize',100,'fontweight','bold','HorizontalAlignment','center');
                        app.UIAxes_force.XLim = [0,10];
                        app.UIAxes_force.YLim = [0,10];
                        app.ScalesEditField.Value = 30;
                        app.ScalesEditFieldValueChanged(app);
                    end
                case 'Gestures'
                    if app.ForceCheckBox.Value
                        cla(app.UIAxes_force);
                        app.UIAxes_force.NextPlot = "add";
                        app.lib_labels = imread('gestures.png');
                        app.lib_labels = imresize(app.lib_labels,[1000,1800]);
                        imagesc(app.UIAxes_force,flipud(app.lib_labels));
                        axis(app.UIAxes_force,'off');
                        app.UIAxes_force.XLim = [0,1800];

                        app.plotForce.shadow = fill(app.UIAxes_force,0,0,[0.7,0.7,0.7],'EdgeColor','none','FaceAlpha',0.5);
                        app.plotForce.shadow2= fill(app.UIAxes_force,0,0,[0.7,0.7,0.7],'EdgeColor','none','FaceAlpha',0.5);
                        app.plotForce.cursor = plot(app.UIAxes_force,0,0,'LineWidth',3,'Color','g');
                        app.plotForce.cursor2 = plot(app.UIAxes_force,0,0,'LineWidth',3,'Color','g');

                        plotAuxData(app,'online',[],20);
                    end
            end
        end

        % Button pushed function: MVCButton
        function MVCButtonPushed(app, event)
            if app.MVCButton.BackgroundColor==[1,1,1]
                app.MVCButton.BackgroundColor = [0,1,0];
                app.edit_MVCvalue.Value = -inf;
                app.UIAxes_force.YLimMode = "auto";
                tmpmvcData = [];
                app.recordFlag = 1;
             
                getData(app.forcePara.ch, getSampleRate(app.forcePara.ch), 50000, app.forcePara.values);
                pause(0.1)
                while app.recordFlag
                    tmpData = readAuxData(app);
                    if mean(tmpData)>app.edit_MVCvalue.Value
                        app.edit_MVCvalue.Value = round(mean(tmpData));
                    end
                    %     values.pvData
                    tmpmvcData = [tmpmvcData,tmpData];
                    t = [1:length(tmpmvcData)]/app.forcePara.fsamp;
                    plotAuxData(app,'MVC',tmpmvcData,t);                 
                    pause(0.1);
                end
                app.mvcData = tmpmvcData;
                app.mvcPara.ma = prctile(tmpmvcData,95);
                app.mvcPara.mi = prctile(tmpmvcData,5);
                plotAuxData(app,'offline',tmpmvcData);
                app.MVCButton.BackgroundColor = [1,1,1];
            else
                app.recordFlag = 0;
                app.MVCButton.BackgroundColor = [1,1,1];
            end
        end

        % Value changed function: CurveDropDown
        function CurveDropDownValueChanged(app, event)
            value = app.CurveDropDown.Value;
            level = app.LevelEditField.Value;
            app.ScalesEditField.Value = str2num(value(end-1:end))+10;
            switch value
                case 'Line-20'
                    x = [0,5,5,25,25,app.ScalesEditField.Value];
                    y = [0,0,1,1,0,0]*level;       
                case 'Tri-20'
                    x = [0,5,15,25,app.ScalesEditField.Value];
                    y = [0,0,1,0,0]*level;
                case 'Tra-20'
                    x = [0,5,10,20,25,app.ScalesEditField.Value];
                    y = [0,0,1,1,0,0]*level;
                case 'Tra-30'
                    x = [0,5,15,25,35,app.ScalesEditField.Value];
                    y = [0,0,1,1,0,0]*level;
            end
            set(app.plotRefForce,'xdata',x,'ydata',y);
            app.UIAxes_force.YLim = [-10,level+10];
            ScalesEditFieldValueChanged(app, event);
        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)
            CurveDropDownValueChanged(app,event);
        end

        % Value changed function: ScalesEditField
        function ScalesEditFieldValueChanged(app, event)
            app.plotTime = app.ScalesEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_EMG = uiaxes(app.UIFigure);
            title(app.UIAxes_EMG, 'EMG signals')
            xlabel(app.UIAxes_EMG, 'Time (s)')
            ylabel(app.UIAxes_EMG, 'Channel')
            zlabel(app.UIAxes_EMG, 'Z')
            app.UIAxes_force = uiaxes(app.UIFigure);
            title(app.UIAxes_force, 'Force signals')
            xlabel(app.UIAxes_force, 'Time (s)')
            ylabel(app.UIAxes_force, 'Channel')
            zlabel(app.UIAxes_force, 'Z')
            app.RecordPanel = uipanel(app.UIFigure);
            app.RecordPanel.Title = 'Record';
            app.StartButton = uibutton(app.RecordPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.RecordPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.DateCheckBox = uicheckbox(app.RecordPanel);
            app.DateCheckBox.Text = '  Date';
            app.AutostopCheckBox = uicheckbox(app.RecordPanel);
            app.AutostopCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutostopCheckBoxValueChanged, true);
            app.AutostopCheckBox.Text = 'Auto stop';
            app.TimesEditFieldLabel = uilabel(app.RecordPanel);
            app.TimesEditFieldLabel.Enable = 'off';
            app.TimesEditFieldLabel.Text = 'Time (s)';
            app.TimesEditField = uieditfield(app.RecordPanel, 'numeric');
            app.TimesEditField.Enable = 'off';
            app.TimesEditField.Value = 30;
            app.TrialSpinnerLabel = uilabel(app.RecordPanel);
            app.TrialSpinnerLabel.Text = 'Trial';
            app.TrialSpinner = uispinner(app.RecordPanel);
            app.TrialSpinner.Value = 1;
            app.TaskSpinnerLabel = uilabel(app.RecordPanel);
            app.TaskSpinnerLabel.Text = 'Task';
            app.TaskSpinner = uispinner(app.RecordPanel);
            app.TaskSpinner.ValueChangedFcn = createCallbackFcn(app, @TaskSpinnerValueChanged, true);
            app.TaskSpinner.Value = 1;
            app.SessionSpinnerLabel = uilabel(app.RecordPanel);
            app.SessionSpinnerLabel.Text = 'Session';
            app.SessionSpinner = uispinner(app.RecordPanel);
            app.SessionSpinner.ValueChangedFcn = createCallbackFcn(app, @SessionSpinnerValueChanged, true);
            app.SessionSpinner.Value = 1;
            app.PrefixEditFieldLabel = uilabel(app.RecordPanel);
            app.PrefixEditFieldLabel.Text = 'Prefix';
            app.PrefixEditField = uieditfield(app.RecordPanel, 'text');
            app.PrefixEditField.ValueChangedFcn = createCallbackFcn(app, @PrefixEditFieldValueChanged, true);
            app.PreviewPanel = uipanel(app.UIFigure);
            app.PreviewPanel.Title = 'Preview';
            app.BandpassCheckBox = uicheckbox(app.PreviewPanel);
            app.BandpassCheckBox.Text = 'Bandpass';
            app.CombCheckBox = uicheckbox(app.PreviewPanel);
            app.CombCheckBox.Text = 'Comb';
            app.PreviewButton = uibutton(app.PreviewPanel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.ChannelEditFieldLabel = uilabel(app.PreviewPanel);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.ChannelEditField = uieditfield(app.PreviewPanel, 'text');
            app.ChannelEditField.Value = '8:16:128';
            app.ScalesEditFieldLabel = uilabel(app.PreviewPanel);
            app.ScalesEditFieldLabel.Text = 'Scale (s)';
            app.ScalesEditField = uieditfield(app.PreviewPanel, 'numeric');
            app.ScalesEditField.ValueChangedFcn = createCallbackFcn(app, @ScalesEditFieldValueChanged, true);
            app.ScalesEditField.Value = 10;
            app.FFTButton = uibutton(app.PreviewPanel, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @FFTButtonPushed, true);
            app.FFTButton.Text = 'FFT';
            app.SelectedchannelEditFieldLabel = uilabel(app.PreviewPanel);
            app.SelectedchannelEditFieldLabel.Text = 'Selected channel';
            app.SelectedchannelEditField = uieditfield(app.PreviewPanel, 'numeric');
            app.SelectedchannelEditField.Value = 10;
            app.IntervalEditFieldLabel = uilabel(app.PreviewPanel);
            app.IntervalEditFieldLabel.Text = 'Interval';
            app.IntervalEditField = uieditfield(app.PreviewPanel, 'numeric');
            app.ConfigurePanel = uipanel(app.UIFigure);
            app.ConfigurePanel.Title = 'Configure';
            app.ConfigureButton = uibutton(app.ConfigurePanel, 'push');
            app.ConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureButtonPushed, true);
            app.ConfigureButton.Text = 'Configure';
            app.DeviceDropDownLabel = uilabel(app.ConfigurePanel);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.ConfigurePanel);
            app.DeviceDropDown.Items = {'Quattrocento', 'MuoviPro', 'EMGUSB2+', 'Sessantaquttro', 'Delsys'};
            app.DeviceDropDown.Value = 'Quattrocento';
            app.ProbeEditFieldLabel = uilabel(app.ConfigurePanel);
            app.ProbeEditFieldLabel.Text = 'Probe';
            app.ProbeEditField = uieditfield(app.ConfigurePanel, 'text');
            app.ProbeEditField.ValueChangedFcn = createCallbackFcn(app, @ProbeEditFieldValueChanged, true);
            app.ProbeEditField.Value = '1:6';
            app.ForcePanel = uipanel(app.UIFigure);
            app.ForcePanel.Title = 'Force';
            app.DeviceDropDown_2Label = uilabel(app.ForcePanel);
            app.DeviceDropDown_2Label.Text = 'Device';
            app.DeviceDropDown_2 = uidropdown(app.ForcePanel);
            app.DeviceDropDown_2.Items = {'Biometrics', 'Handwriting', 'Gestures'};
            app.DeviceDropDown_2.Value = 'Biometrics';
            app.ForceCheckBox = uicheckbox(app.ForcePanel);
            app.ForceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForceCheckBoxValueChanged, true);
            app.ForceCheckBox.Text = 'Force';
            app.MVCButton = uibutton(app.ForcePanel, 'push');
            app.MVCButton.ButtonPushedFcn = createCallbackFcn(app, @MVCButtonPushed, true);
            app.MVCButton.Text = 'MVC';
            app.LevelEditFieldLabel = uilabel(app.ForcePanel);
            app.LevelEditFieldLabel.Text = 'Level';
            app.LevelEditField = uieditfield(app.ForcePanel, 'numeric');
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.LevelEditField.Value = 10;
            app.CurveDropDownLabel = uilabel(app.ForcePanel);
            app.CurveDropDownLabel.Text = 'Curve';
            app.CurveDropDown = uidropdown(app.ForcePanel);
            app.CurveDropDown.Items = {'Line-20', 'Tri-20', 'Tra-20', 'Tra-30'};
            app.CurveDropDown.ValueChangedFcn = createCallbackFcn(app, @CurveDropDownValueChanged, true);
            app.CurveDropDown.Value = 'Line-20';
            app.edit_MVCvalue = uieditfield(app.ForcePanel, 'numeric');
            app.edit_MVCvalue.Value = -Inf;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dataRecording

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end